classdef BrakingAbaqus < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AccSlider                       matlab.ui.control.Slider
        AccSliderLabel                  matlab.ui.control.Label
        BREAKINGABAQUS10Label           matlab.ui.control.Label
        EXITButton                      matlab.ui.control.Button
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        cmLabel_4                       matlab.ui.control.Label
        cmLabel_3                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        KgLabel                         matlab.ui.control.Label
        WheelREditField                 matlab.ui.control.NumericEditField
        WheelREditFieldLabel            matlab.ui.control.Label
        HpREditField                    matlab.ui.control.NumericEditField
        HpREditFieldLabel               matlab.ui.control.Label
        RextREditField                  matlab.ui.control.NumericEditField
        RextREditFieldLabel             matlab.ui.control.Label
        AwcREditField                   matlab.ui.control.NumericEditField
        AwcREditFieldLabel              matlab.ui.control.Label
        AmcREditField                   matlab.ui.control.NumericEditField
        AmcREditFieldLabel              matlab.ui.control.Label
        WheelFEditField                 matlab.ui.control.NumericEditField
        WheelFEditFieldLabel            matlab.ui.control.Label
        HpFEditField                    matlab.ui.control.NumericEditField
        HpFEditFieldLabel               matlab.ui.control.Label
        RextFEditField                  matlab.ui.control.NumericEditField
        RextFEditFieldLabel             matlab.ui.control.Label
        AwcFEditField                   matlab.ui.control.NumericEditField
        AwcFEditFieldLabel              matlab.ui.control.Label
        AmcFEditField                   matlab.ui.control.NumericEditField
        AmcFEditFieldLabel              matlab.ui.control.Label
        PedalRatioEditField             matlab.ui.control.NumericEditField
        PedalRatioEditFieldLabel        matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        MassEditFieldLabel              matlab.ui.control.Label
        GraphTypeDropDown               matlab.ui.control.DropDown
        GraphTypeDropDownLabel          matlab.ui.control.Label
        BBBSlider                       matlab.ui.control.Slider
        BBBSliderLabel                  matlab.ui.control.Label
        chiSlider                       matlab.ui.control.Slider
        chiSliderLabel                  matlab.ui.control.Label
        PsiSlider                       matlab.ui.control.Slider
        PsiLabel                        matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        BarRearBrakePressureGauge       matlab.ui.control.Gauge
        BarRearBrakePressureGaugeLabel  matlab.ui.control.Label
        BarFrontBrakePressureGauge      matlab.ui.control.Gauge
        BarFrontBrakePressureGaugeLabel  matlab.ui.control.Label
        kgPedalForceGauge               matlab.ui.control.Gauge
        kgPedalForceGaugeLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        B BrakeClass
        folder string = string.empty;
        model string = string.empty;
        GraphType string = string.empty;

        % Initial Parameters
        parameters = zeros(7,3);
        psi = 0.5;
        chi = 0.2;
        BBB = 0.5;
        acc = 1;
    end
    
    methods (Access = private)
        
        function PLOT(app)           

            switch app.GraphTypeDropDown.Value

                case 'Brake Line' 
                    hold(app.UIAxes, 'off')

                    plotlimit = 1;  

                    % --------------- Lines of constant -----------------                    
                
                    for i = 1:length(app.B.Fx_optm)
                        if (app.B.Fx_optm(i,1)/app.B.W)+(app.B.Fx_optm(i,2)/app.B.W) == plotlimit
                            break
                        end
                    end

                    x1 = app.B.Fx_optm(1:i,2)./app.B.W;
                    y1 = app.B.Fx_optm(1:i,1)./app.B.W;
                    x2 = linspace(0, app.B.mu_T(1), length(app.B.Fx_optm(1:i,2)));
                    y2 = linspace(app.B.mu_T(1), 0, length(app.B.Fx_optm(1:i,2)));

                    % intesection of lines
                    [xi, yi] = polyxpoly(x1, y1, x2, y2, 'unique');
        
                    axis(app.UIAxes, [0 1 0 1]) % define axis limits

                    % ---------------------------------------------------
        
                    for j = 0.1:0.1:1               
                        plot(app.UIAxes, [0 j], [j 0], 'g--')
                        hold(app.UIAxes, "on")
                    end       
        
                    plot(app.UIAxes, x1, y1) % optimum braking line
                    plot(app.UIAxes, [0 xi], [app.B.a_fr(1) yi], 'r-') % line of disconnected front axel
                    plot(app.UIAxes, [app.B.a_fr(2) xi], [0 yi], 'r-') % line of disconnected rear axel                    

                    for j = 1:length(app.B.Fx_optm)
                        if (app.B.Fx_real(j,1)/app.B.W)+(app.B.Fx_real(j,2)/app.B.W) == plotlimit
                            break
                        end
                    end

                    plot(app.UIAxes, app.B.Fx_real(1:j,2)./app.B.W, app.B.Fx_real(1:j,1)./app.B.W)
                    xlabel(app.UIAxes, 'Dynamic Rear Axle Brake Force (Normalized)')
                    ylabel(app.UIAxes, 'Dynamic Front Axle Brake Force (Normalized)')
                    title(app.UIAxes, 'Brake line')
                    hold(app.UIAxes, "off")

                case 'Pressure' 
                    
                    hold(app.UIAxes, 'off')

                    x = app.B.Fpedal_var./app.B.g;
                    y = app.B.Pl(:, 1).*0.1 + app.B.Pl(:, 2).*0.1;
                    axis(app.UIAxes, [0 x(end) 0 y(end)])
                    title(app.UIAxes, 'Hydraulic Pressure')
                    
                    ylabel(app.UIAxes, 'Hydraulic Pressure [Bar]')                    
                    xlabel(app.UIAxes, 'Pedal Force [Kg]')                                     

                    y = app.B.Pl(:, 1).*0.1;
                    plot(app.UIAxes, x, y, 'DisplayName', 'Front Pressure')
                    hold(app.UIAxes, 'on')

                    y = app.B.Pl(:, 2).*0.1;
                    plot(app.UIAxes, x, y, 'DisplayName', 'Rear Pressure')
                    
                    legend(app.UIAxes)
                    hold(app.UIAxes, 'off')

                case 'Braking Force'
                    
                    hold(app.UIAxes, 'off')

                    x = app.B.Fpedal_var./app.B.g;
                    y = app.B.Fx_real(:, 1)./app.B.W + app.B.Fx_real(:, 2)./app.B.W;
                    axis(app.UIAxes, [0 x(end) 0 y(end)])
                    title(app.UIAxes, 'Braking Forces')
                    
                    ylabel(app.UIAxes, 'Braking Force (Normalized)')                    
                    xlabel(app.UIAxes, 'Pedal Force [Kg]')
                    
                    plot(app.UIAxes, x, y, 'DisplayName', 'Total Braking force (Normalized)') 
                    hold(app.UIAxes, 'on')

                    y = app.B.Fx_real(:, 1)./app.B.W;
                    plot(app.UIAxes, x, y, 'DisplayName', 'Front Braking force (Normalized)')

                    y = app.B.Fx_real(:, 2)./app.B.W;
                    plot(app.UIAxes, x, y, 'DisplayName', 'Rear Braking force (Normalized)')
                    
                    legend(app.UIAxes)
                    hold(app.UIAxes, 'off')                                                         
                    
            end            
        end
        
        function CALCULATE(app)        
            app.B.solveOptBrake
            app.B.solveRealBrake
            app.B.solvemu_real
            app.B.solvemu_optm
            app.B.solveFpedal(app.acc)
            app.B.solvemu(app.acc)
            app.B.solvemu_real
            app.B.solvemu_optm
            app.B.solveCntFriction
            app.B.solveBrakeEff
            app.kgPedalForceGauge.Value = app.B.Fpedal;
            app.BarFrontBrakePressureGauge.Value = app.B.Pl_Pedal(1);
            app.BarRearBrakePressureGauge.Value = app.B.Pl_Pedal(2);
            
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PsiSlider
        function PsiSliderValueChanged(app, event)
            app.psi = app.PsiSlider.Value;
            app.CALCULATE
            app.PLOT
        end

        % Value changed function: chiSlider
        function chiSliderValueChanged(app, event)
            app.chi = app.chiSlider.Value;
            app.CALCULATE
            app.PLOT
        end

        % Value changed function: BBBSlider
        function BBBSliderValueChanged(app, event)
            value = app.BBBSlider.Value; 
            app.BBB = value;
            app.CALCULATE
            app.PLOT
        end

        % Callback function: LoadButton, UIFigure
        function LoadButtonPushed(app, event)
            %% Load parameters
            clc
            clear app.B
            fprintf('==== Brake Analysis Started ====')
                       
            %% Create Brake object
            disp('---- Calculation starte ----')
            app.B = BrakeClass("NONE", "SET");      
            app.B.ReadParameters(app.psi, app.chi, app.BBB, app.parameters)
            app.CALCULATE
            app.PLOT
            disp(app.B)
            disp(app.parameters)
        end

        % Value changed function: MassEditField
        function MassEditFieldValueChanged(app, event)
            value = app.MassEditField.Value;
            app.parameters(1, 2) = value;
        end

        % Value changed function: PedalRatioEditField
        function PedalRatioEditFieldValueChanged(app, event)
            value = app.PedalRatioEditField.Value;
            app.parameters(2, 2) = value;
        end

        % Value changed function: AmcFEditField
        function AmcFEditFieldValueChanged(app, event)
            value = app.AmcFEditField.Value;
            app.parameters(3, 2) = value;
        end

        % Value changed function: AmcREditField
        function AmcREditFieldValueChanged(app, event)
            value = app.AmcREditField.Value;
            app.parameters(3, 3) = value;
        end

        % Value changed function: AwcFEditField
        function AwcFEditFieldValueChanged(app, event)
            value = app.AwcFEditField.Value;
            app.parameters(4, 2) = value;
        end

        % Value changed function: AwcREditField
        function AwcREditFieldValueChanged(app, event)
            value = app.AwcREditField.Value;
            app.parameters(4, 3) = value;
        end

        % Value changed function: RextFEditField
        function RextFEditFieldValueChanged(app, event)
            value = app.RextFEditField.Value;
            app.parameters(5, 2) = value;
        end

        % Value changed function: RextREditField
        function RextREditFieldValueChanged(app, event)
            value = app.RextREditField.Value;
            app.parameters(5, 3) = value;            
        end

        % Value changed function: HpFEditField
        function HpFEditFieldValueChanged(app, event)
            value = app.HpFEditField.Value;
            app.parameters(6, 2) = value;            
        end

        % Value changed function: HpREditField
        function HpREditFieldValueChanged(app, event)
            value = app.HpREditField.Value;
            app.parameters(6, 3) = value;            
        end

        % Value changed function: WheelFEditField
        function WheelFEditFieldValueChanged(app, event)
            value = app.WheelFEditField.Value;
            app.parameters(7, 2) = value;
        end

        % Value changed function: WheelREditField
        function WheelREditFieldValueChanged(app, event)
            value = app.WheelREditField.Value;
            app.parameters(7, 3) = value;            
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            value = app.GraphTypeDropDown.Value;
            app.GraphType = value;           
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end

        % Value changed function: AccSlider
        function AccSliderValueChanged(app, event)
            value = app.AccSlider.Value;
            app.acc = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {577, 577};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {315, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PsiLabel = uilabel(app.LeftPanel);
            app.PsiLabel.Interpreter = 'latex';
            app.PsiLabel.Text = '\Psi';
            app.PsiSlider = uislider(app.LeftPanel);
            app.PsiSlider.Limits = [0 1];
            app.PsiSlider.ValueChangedFcn = createCallbackFcn(app, @PsiSliderValueChanged, true);
            app.PsiSlider.Value = 0.5;
            app.chiSliderLabel = uilabel(app.LeftPanel);
            app.chiSliderLabel.Interpreter = 'latex';
            app.chiSliderLabel.Text = '\chi';
            app.chiSlider = uislider(app.LeftPanel);
            app.chiSlider.Limits = [0 1];
            app.chiSlider.ValueChangedFcn = createCallbackFcn(app, @chiSliderValueChanged, true);
            app.chiSlider.Value = 0.2;
            app.BBBSliderLabel = uilabel(app.LeftPanel);
            app.BBBSliderLabel.Text = 'BBB';
            app.BBBSlider = uislider(app.LeftPanel);
            app.BBBSlider.Limits = [0 1];
            app.BBBSlider.ValueChangedFcn = createCallbackFcn(app, @BBBSliderValueChanged, true);
            app.BBBSlider.Value = 0.5;
            app.GraphTypeDropDownLabel = uilabel(app.LeftPanel);
            app.GraphTypeDropDownLabel.Text = 'Graph Type';
            app.GraphTypeDropDown = uidropdown(app.LeftPanel);
            app.GraphTypeDropDown.Items = {'Brake Line', 'Pressure', 'Braking Force'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Brake Line';
            app.MassEditFieldLabel = uilabel(app.LeftPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MassEditField.ValueChangedFcn = createCallbackFcn(app, @MassEditFieldValueChanged, true);
            app.PedalRatioEditFieldLabel = uilabel(app.LeftPanel);
            app.PedalRatioEditFieldLabel.Text = 'Pedal Ratio';
            app.PedalRatioEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PedalRatioEditField.ValueChangedFcn = createCallbackFcn(app, @PedalRatioEditFieldValueChanged, true);
            app.AmcFEditFieldLabel = uilabel(app.LeftPanel);
            app.AmcFEditFieldLabel.Text = 'Amc F';
            app.AmcFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmcFEditField.ValueChangedFcn = createCallbackFcn(app, @AmcFEditFieldValueChanged, true);
            app.AwcFEditFieldLabel = uilabel(app.LeftPanel);
            app.AwcFEditFieldLabel.Text = 'Awc F';
            app.AwcFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AwcFEditField.ValueChangedFcn = createCallbackFcn(app, @AwcFEditFieldValueChanged, true);
            app.RextFEditFieldLabel = uilabel(app.LeftPanel);
            app.RextFEditFieldLabel.Text = 'Rext F';
            app.RextFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RextFEditField.ValueChangedFcn = createCallbackFcn(app, @RextFEditFieldValueChanged, true);
            app.HpFEditFieldLabel = uilabel(app.LeftPanel);
            app.HpFEditFieldLabel.Text = 'Hp F';
            app.HpFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HpFEditField.ValueChangedFcn = createCallbackFcn(app, @HpFEditFieldValueChanged, true);
            app.WheelFEditFieldLabel = uilabel(app.LeftPanel);
            app.WheelFEditFieldLabel.Text = 'Wheel F';
            app.WheelFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WheelFEditField.ValueChangedFcn = createCallbackFcn(app, @WheelFEditFieldValueChanged, true);
            app.AmcREditFieldLabel = uilabel(app.LeftPanel);
            app.AmcREditFieldLabel.Text = 'Amc R';
            app.AmcREditField = uieditfield(app.LeftPanel, 'numeric');
            app.AmcREditField.ValueChangedFcn = createCallbackFcn(app, @AmcREditFieldValueChanged, true);
            app.AwcREditFieldLabel = uilabel(app.LeftPanel);
            app.AwcREditFieldLabel.Text = 'Awc R';
            app.AwcREditField = uieditfield(app.LeftPanel, 'numeric');
            app.AwcREditField.ValueChangedFcn = createCallbackFcn(app, @AwcREditFieldValueChanged, true);
            app.RextREditFieldLabel = uilabel(app.LeftPanel);
            app.RextREditFieldLabel.Text = 'Rext R';
            app.RextREditField = uieditfield(app.LeftPanel, 'numeric');
            app.RextREditField.ValueChangedFcn = createCallbackFcn(app, @RextREditFieldValueChanged, true);
            app.HpREditFieldLabel = uilabel(app.LeftPanel);
            app.HpREditFieldLabel.Text = 'Hp R';
            app.HpREditField = uieditfield(app.LeftPanel, 'numeric');
            app.HpREditField.ValueChangedFcn = createCallbackFcn(app, @HpREditFieldValueChanged, true);
            app.WheelREditFieldLabel = uilabel(app.LeftPanel);
            app.WheelREditFieldLabel.Text = 'Wheel R';
            app.WheelREditField = uieditfield(app.LeftPanel, 'numeric');
            app.WheelREditField.ValueChangedFcn = createCallbackFcn(app, @WheelREditFieldValueChanged, true);
            app.KgLabel = uilabel(app.LeftPanel);
            app.KgLabel.Text = 'Kg';
            app.cmLabel = uilabel(app.LeftPanel);
            app.cmLabel.Text = 'cm²';
            app.cmLabel_2 = uilabel(app.LeftPanel);
            app.cmLabel_2.Text = 'cm²';
            app.mmLabel = uilabel(app.LeftPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.LeftPanel);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.LeftPanel);
            app.mmLabel_3.Text = 'mm';
            app.cmLabel_3 = uilabel(app.LeftPanel);
            app.cmLabel_3.Text = 'cm²';
            app.cmLabel_4 = uilabel(app.LeftPanel);
            app.cmLabel_4.Text = 'cm²';
            app.mmLabel_5 = uilabel(app.LeftPanel);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_4 = uilabel(app.LeftPanel);
            app.mmLabel_4.Text = 'mm';
            app.mmLabel_6 = uilabel(app.LeftPanel);
            app.mmLabel_6.Text = 'mm';
            app.EXITButton = uibutton(app.LeftPanel, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.BREAKINGABAQUS10Label = uilabel(app.LeftPanel);
            app.BREAKINGABAQUS10Label.Text = 'BREAKING ABAQUS 1.0';
            app.AccSliderLabel = uilabel(app.LeftPanel);
            app.AccSliderLabel.Text = 'Acc';
            app.AccSlider = uislider(app.LeftPanel);
            app.AccSlider.Limits = [0 1];
            app.AccSlider.ValueChangedFcn = createCallbackFcn(app, @AccSliderValueChanged, true);
            app.AccSlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 Inf];
            app.UIAxes.YLim = [0 Inf];
            app.UIAxes.ZLim = [0 Inf];
            app.kgPedalForceGaugeLabel = uilabel(app.RightPanel);
            app.kgPedalForceGaugeLabel.Text = 'kg [Pedal Force]';
            app.kgPedalForceGauge = uigauge(app.RightPanel, 'circular');
            app.BarFrontBrakePressureGaugeLabel = uilabel(app.RightPanel);
            app.BarFrontBrakePressureGaugeLabel.Text = 'Bar [Front Brake Pressure]';
            app.BarFrontBrakePressureGauge = uigauge(app.RightPanel, 'circular');
            app.BarRearBrakePressureGaugeLabel = uilabel(app.RightPanel);
            app.BarRearBrakePressureGaugeLabel.Text = 'Bar [Rear Brake Pressure]';
            app.BarRearBrakePressureGauge = uigauge(app.RightPanel, 'circular');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.AmcFEditFieldLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrakingAbaqus

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end