classdef BrakeV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        muRearSlider              matlab.ui.control.Slider
        muRearSliderLabel         matlab.ui.control.Label
        muFrontSlider             matlab.ui.control.Slider
        muFrontLabel              matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        BBBSlider                 matlab.ui.control.Slider
        BBBSliderLabel            matlab.ui.control.Label
        chiSlider                 matlab.ui.control.Slider
        chiSliderLabel            matlab.ui.control.Label
        PsiSlider                 matlab.ui.control.Slider
        PsiLabel                  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        ParametersPanel           matlab.ui.container.Panel
        WheelREditField           matlab.ui.control.NumericEditField
        WheelREditFieldLabel      matlab.ui.control.Label
        HpREditField              matlab.ui.control.NumericEditField
        HpREditFieldLabel         matlab.ui.control.Label
        RextREditField            matlab.ui.control.NumericEditField
        RextREditFieldLabel       matlab.ui.control.Label
        DwcREditField             matlab.ui.control.NumericEditField
        DwcREditFieldLabel        matlab.ui.control.Label
        DmcREditField             matlab.ui.control.NumericEditField
        DmcREditFieldLabel        matlab.ui.control.Label
        WheelFEditField           matlab.ui.control.NumericEditField
        WheelFEditFieldLabel      matlab.ui.control.Label
        HpFEditField              matlab.ui.control.NumericEditField
        HpFEditFieldLabel         matlab.ui.control.Label
        RextFEditField            matlab.ui.control.NumericEditField
        RextFEditFieldLabel       matlab.ui.control.Label
        DwcFEditField             matlab.ui.control.NumericEditField
        DwcFEditFieldLabel        matlab.ui.control.Label
        DmcFEditField             matlab.ui.control.NumericEditField
        DmcFEditFieldLabel        matlab.ui.control.Label
        PedalRatioEditField       matlab.ui.control.NumericEditField
        PedalRatioEditFieldLabel  matlab.ui.control.Label
        MassEditField             matlab.ui.control.NumericEditField
        MassEditFieldLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        B BrakeClass
        folder string
        model string
        parameters double

        psi = 0.5;
        chi = 0.2;
        BBB = 0.5;
        mu = [0.8 0.8];
    end
    
    methods (Access = private)
        
        function PLOT(app)
            
            plotlimit = 1;

            for i = 1:length(app.B.Fx_optm)
                if (app.B.Fx_optm(i,1)/app.B.W)+(app.B.Fx_optm(i,2)/app.B.W) == plotlimit
                    break
                end
            end

            x1 = app.B.Fx_optm(1:i,2)./app.B.W;
            y1 = app.B.Fx_optm(1:i,1)./app.B.W;
            x2 = linspace(0, app.B.mu(1), length(app.B.Fx_optm(1:i,2)));
            y2 = linspace(app.B.mu(1), 0, length(app.B.Fx_optm(1:i,2)));

            [xi, yi] = polyxpoly(x1, y1, x2, y2, 'unique');

            axis(app.UIAxes, [0 1 0 1])           

            for j = 0.1:0.1:1               
                plot(app.UIAxes, [0 j], [j 0], 'g--')
                hold(app.UIAxes, "on")
            end       

            plot(app.UIAxes, app.B.Fx_optm(1:i,2)./app.B.W, app.B.Fx_optm(1:i,1)./app.B.W)
            plot(app.UIAxes, [0 xi], [app.B.a_fr(1) yi], 'r-')
            plot(app.UIAxes, [app.B.a_fr(2) xi], [0 yi], 'r-')
            
            plot(app.UIAxes, app.B.phi.*app.B.Acc.Read.data(:, 2), linspace(0, 1, length(app.B.Acc.Read.data(:, 2))))
            hold(app.UIAxes, "off")
             
        end
        
        function CALCULATE(app)        
            app.B.calcOptBrake
            app.B.calcRealBrake
            app.B.calcCntFriction
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PsiSlider
        function PsiSliderValueChanged(app, event)
            app.psi = app.PsiSlider.Value;
            app.CALCULATE
            app.PLOT
        end

        % Value changed function: chiSlider
        function chiSliderValueChanged(app, event)
            app.chi = app.chiSlider.Value;
            app.CALCULATE
            app.PLOT
        end

        % Value changed function: BBBSlider
        function BBBSliderValueChanged(app, event)
            value = app.BBBSlider.Value; 
            app.BBB = value;
            app.CALCULATE
            app.PLOT
        end

        % Callback function: not associated with a component
        function ParametersEditFieldValueChanged(app, event)
            value = app.ParametersEditField.Value;
            app.folder = value;
        end

        % Callback function: LoadButton, UIFigure
        function LoadButtonPushed(app, event)
            %% Load parameters
            clc
            clear app.B
            fprintf('==== Brake Analysis Started ====')
            filename = string(app.folder) + "\" + string(app.model) + ".xlsx";
            fprintf('\nParameters arquive parth : %s', filename)
            app.parameters = readmatrix(filename);
            
            %% Create Brake object
            disp('---- Calculation starte ----')
            app.B = BrakeClass("NONE", "SET", app.psi, app.chi, app.BBB, app.parameters);
            
            app.CALCULATE
            app.PLOT

        end

        % Callback function
        function LoggerEditFieldValueChanged(app, event)

        end

        % Value changed function: muFrontSlider
        function muFrontSliderValueChanged(app, event)
            value = app.muFrontSlider.Value;
            app.mu(1) = value;
            app.CALCULATE
            app.PLOT
        end

        % Value changed function: muRearSlider
        function muRearSliderValueChanged(app, event)
            value = app.muRearSlider.Value;
            app.mu(2) = value;
            app.CALCULATE
            app.PLOT
        end

        % Callback function: not associated with a component
        function ModelEditFieldValueChanged(app, event)
            value = app.ModelEditField.Value;
            app.string = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {577, 577};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {315, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PsiLabel = uilabel(app.LeftPanel);
            app.PsiLabel.Interpreter = 'latex';
            app.PsiLabel.Text = '\Psi';
            app.PsiSlider = uislider(app.LeftPanel);
            app.PsiSlider.Limits = [0 1];
            app.PsiSlider.ValueChangedFcn = createCallbackFcn(app, @PsiSliderValueChanged, true);
            app.PsiSlider.Value = 0.5;
            app.chiSliderLabel = uilabel(app.LeftPanel);
            app.chiSliderLabel.Interpreter = 'latex';
            app.chiSliderLabel.Text = '\chi';
            app.chiSlider = uislider(app.LeftPanel);
            app.chiSlider.Limits = [0 1];
            app.chiSlider.ValueChangedFcn = createCallbackFcn(app, @chiSliderValueChanged, true);
            app.chiSlider.Value = 0.2;
            app.BBBSliderLabel = uilabel(app.LeftPanel);
            app.BBBSliderLabel.Text = 'BBB';
            app.BBBSlider = uislider(app.LeftPanel);
            app.BBBSlider.Limits = [0 1];
            app.BBBSlider.ValueChangedFcn = createCallbackFcn(app, @BBBSliderValueChanged, true);
            app.BBBSlider.Value = 0.5;
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.muFrontLabel = uilabel(app.LeftPanel);
            app.muFrontLabel.Interpreter = 'latex';
            app.muFrontLabel.Text = {'\mu'; ''; 'Front'};
            app.muFrontSlider = uislider(app.LeftPanel);
            app.muFrontSlider.Limits = [0 1];
            app.muFrontSlider.ValueChangedFcn = createCallbackFcn(app, @muFrontSliderValueChanged, true);
            app.muFrontSlider.Value = 0.8;
            app.muRearSliderLabel = uilabel(app.LeftPanel);
            app.muRearSliderLabel.Interpreter = 'latex';
            app.muRearSliderLabel.Text = {'\mu'; ''; 'Rear'};
            app.muRearSlider = uislider(app.LeftPanel);
            app.muRearSlider.Limits = [0 1];
            app.muRearSlider.ValueChangedFcn = createCallbackFcn(app, @muRearSliderValueChanged, true);
            app.muRearSlider.Value = 0.8;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Braking Line')
            xlabel(app.UIAxes, 'Dynamic Rear Axle Brake Force (Normalized)')
            ylabel(app.UIAxes, 'Dynamic Front Axle Brake Force (Normalized)')
            zlabel(app.UIAxes, 'Z')
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.MassEditFieldLabel = uilabel(app.ParametersPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.PedalRatioEditFieldLabel = uilabel(app.ParametersPanel);
            app.PedalRatioEditFieldLabel.Text = 'Pedal Ratio';
            app.PedalRatioEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DmcFEditFieldLabel = uilabel(app.ParametersPanel);
            app.DmcFEditFieldLabel.Text = 'Dmc F';
            app.DmcFEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DwcFEditFieldLabel = uilabel(app.ParametersPanel);
            app.DwcFEditFieldLabel.Text = 'Dwc F';
            app.DwcFEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RextFEditFieldLabel = uilabel(app.ParametersPanel);
            app.RextFEditFieldLabel.Text = 'Rext F';
            app.RextFEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.HpFEditFieldLabel = uilabel(app.ParametersPanel);
            app.HpFEditFieldLabel.Text = 'Hp F';
            app.HpFEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.WheelFEditFieldLabel = uilabel(app.ParametersPanel);
            app.WheelFEditFieldLabel.Text = 'Wheel F';
            app.WheelFEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DmcREditFieldLabel = uilabel(app.ParametersPanel);
            app.DmcREditFieldLabel.Text = 'Dmc R';
            app.DmcREditField = uieditfield(app.ParametersPanel, 'numeric');
            app.DwcREditFieldLabel = uilabel(app.ParametersPanel);
            app.DwcREditFieldLabel.Text = 'Dwc R';
            app.DwcREditField = uieditfield(app.ParametersPanel, 'numeric');
            app.RextREditFieldLabel = uilabel(app.ParametersPanel);
            app.RextREditFieldLabel.Text = 'Rext R';
            app.RextREditField = uieditfield(app.ParametersPanel, 'numeric');
            app.HpREditFieldLabel = uilabel(app.ParametersPanel);
            app.HpREditFieldLabel.Text = 'Hp R';
            app.HpREditField = uieditfield(app.ParametersPanel, 'numeric');
            app.WheelREditFieldLabel = uilabel(app.ParametersPanel);
            app.WheelREditFieldLabel.Text = 'Wheel R';
            app.WheelREditField = uieditfield(app.ParametersPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrakeV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end